classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label_Result              matlab.ui.control.Label
        Label_Result0             matlab.ui.control.Label
        InfluentTPLabel_4         matlab.ui.control.Label
        InfluentTNLabel_2         matlab.ui.control.Label
        InfluentTPLabel_3         matlab.ui.control.Label
        InfluentTPLabel_2         matlab.ui.control.Label
        InfluentTPLabel           matlab.ui.control.Label
        InfluentTNLabel           matlab.ui.control.Label
        EditField_Cost            matlab.ui.control.EditField
        InfluentTNLabel_6         matlab.ui.control.Label
        EditField_Sustainability  matlab.ui.control.EditField
        InfluentTNLabel_5         matlab.ui.control.Label
        InfluentTNLabel_4         matlab.ui.control.Label
        EditField_MassRatio       matlab.ui.control.EditField
        OptimalSSCReFschemeLabel  matlab.ui.control.Label
        EffluentTPLabel           matlab.ui.control.Label
        EffluentTNLabel           matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        EditField_TP_removal      matlab.ui.control.NumericEditField
        PredictOptimizeMultiObjectiveLabel  matlab.ui.control.Label
        Button_Reset              matlab.ui.control.Button
        EditField_TN_removal      matlab.ui.control.NumericEditField
        Button_Run                matlab.ui.control.Button
        EditField_EBCT            matlab.ui.control.NumericEditField
        hLabel                    matlab.ui.control.Label
        EditField_alkalinity      matlab.ui.control.NumericEditField
        mgLLabel_3                matlab.ui.control.Label
        EditField_TP              matlab.ui.control.NumericEditField
        mgLLabel_2                matlab.ui.control.Label
        EditField_TN              matlab.ui.control.NumericEditField
        mgLLabel                  matlab.ui.control.Label
        RegulatoryCompliantObjectivesLabel  matlab.ui.control.Label
        WaterQualityOperationalParametersLabel  matlab.ui.control.Label
        SSCReFPerformancePredictionandMultiObjectiveoptimizationLabel  matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Run
        function Button_RunPushed(app, event)
            app.Button_Run.Enable = "off";
            app.Button_Reset.Enable = "off";
            app.Label.Text = 'calculating';
            drawnow;
            main(app);
            app.Label.Text = 'calculation completed';
            app.Button_Run.Enable = "on";
            app.Button_Reset.Enable = "on";
        end

        % Button pushed function: Button_Reset
        function Button_ResetPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            legend(app.UIAxes, 'off');
            legend(app.UIAxes_2, 'off');

            app.EditField_MassRatio.Value = '';
            app.EditField_Sustainability.Value = '';
            app.EditField_Cost.Value = '';

            app.Label.Text = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SSCReF Performance Prediction and Multi-Objective optimization ';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'S wt% in SSCReF')
            ylabel(app.UIAxes, 'TN')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'S wt% in SSCReF')
            ylabel(app.UIAxes_2, 'TP')
            zlabel(app.UIAxes_2, 'Z')
            app.SSCReFPerformancePredictionandMultiObjectiveoptimizationLabel = uilabel(app.UIFigure);
            app.SSCReFPerformancePredictionandMultiObjectiveoptimizationLabel.Text = 'SSCReF Performance Prediction and Multi-Objective optimization ';
            app.WaterQualityOperationalParametersLabel = uilabel(app.UIFigure);
            app.WaterQualityOperationalParametersLabel.Text = 'Water Quality & Operational Parameters';
            app.RegulatoryCompliantObjectivesLabel = uilabel(app.UIFigure);
            app.RegulatoryCompliantObjectivesLabel.Text = 'Regulatory-Compliant Objectives';
            app.mgLLabel = uilabel(app.UIFigure);
            app.mgLLabel.Text = '(5-50 mg/L)';
            app.EditField_TN = uieditfield(app.UIFigure, 'numeric');
            app.EditField_TN.Value = 20;
            app.mgLLabel_2 = uilabel(app.UIFigure);
            app.mgLLabel_2.Text = '(0-1.5 mg/L)';
            app.EditField_TP = uieditfield(app.UIFigure, 'numeric');
            app.EditField_TP.Value = 1;
            app.mgLLabel_3 = uilabel(app.UIFigure);
            app.mgLLabel_3.Text = '(50-500 mg/L)';
            app.EditField_alkalinity = uieditfield(app.UIFigure, 'numeric');
            app.EditField_alkalinity.Value = 150;
            app.hLabel = uilabel(app.UIFigure);
            app.hLabel.Text = '(0.5-6 h)';
            app.EditField_EBCT = uieditfield(app.UIFigure, 'numeric');
            app.EditField_EBCT.Value = 1;
            app.Button_Run = uibutton(app.UIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Button_RunPushed, true);
            app.Button_Run.Text = 'Run';
            app.EditField_TN_removal = uieditfield(app.UIFigure, 'numeric');
            app.EditField_TN_removal.Value = 10;
            app.Button_Reset = uibutton(app.UIFigure, 'push');
            app.Button_Reset.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetPushed, true);
            app.Button_Reset.Text = 'Reset';
            app.PredictOptimizeMultiObjectiveLabel = uilabel(app.UIFigure);
            app.PredictOptimizeMultiObjectiveLabel.Text = 'Predict & Optimize (Multi-Objective)';
            app.EditField_TP_removal = uieditfield(app.UIFigure, 'numeric');
            app.EditField_TP_removal.Value = 0.6;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EffluentTNLabel = uilabel(app.UIFigure);
            app.EffluentTNLabel.Text = 'Effluent TN';
            app.EffluentTPLabel = uilabel(app.UIFigure);
            app.EffluentTPLabel.Text = 'Effluent TP';
            app.OptimalSSCReFschemeLabel = uilabel(app.UIFigure);
            app.OptimalSSCReFschemeLabel.Text = 'Optimal SSCReF scheme';
            app.EditField_MassRatio = uieditfield(app.UIFigure, 'text');
            app.InfluentTNLabel_4 = uilabel(app.UIFigure);
            app.InfluentTNLabel_4.Interpreter = 'html';
            app.InfluentTNLabel_4.Text = '<p>S<sup>0</sup>/Fe<sup>(Ⅱ)</sup> mass ratio</p>';
            app.InfluentTNLabel_5 = uilabel(app.UIFigure);
            app.InfluentTNLabel_5.Interpreter = 'html';
            app.InfluentTNLabel_5.Text = 'Sustainability';
            app.EditField_Sustainability = uieditfield(app.UIFigure, 'text');
            app.InfluentTNLabel_6 = uilabel(app.UIFigure);
            app.InfluentTNLabel_6.Interpreter = 'html';
            app.InfluentTNLabel_6.Text = 'Cost (USD/t water)';
            app.EditField_Cost = uieditfield(app.UIFigure, 'text');
            app.InfluentTNLabel = uilabel(app.UIFigure);
            app.InfluentTNLabel.Text = 'Influent TN';
            app.InfluentTPLabel = uilabel(app.UIFigure);
            app.InfluentTPLabel.Text = 'Influent TP';
            app.InfluentTPLabel_2 = uilabel(app.UIFigure);
            app.InfluentTPLabel_2.Text = 'Influent alkalinity';
            app.InfluentTPLabel_3 = uilabel(app.UIFigure);
            app.InfluentTPLabel_3.Text = 'EBCT';
            app.InfluentTNLabel_2 = uilabel(app.UIFigure);
            app.InfluentTNLabel_2.Text = 'TN removal (mg/L)';
            app.InfluentTPLabel_4 = uilabel(app.UIFigure);
            app.InfluentTPLabel_4.Text = 'TP removal (mg/L)';
            app.Label_Result0 = uilabel(app.UIFigure);
            app.Label_Result0.Text = 'Result:';
            app.Label_Result = uilabel(app.UIFigure);
            app.Label_Result.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end